classdef tpms < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GapLabel                   matlab.ui.control.Label
        Gauge                      matlab.ui.control.SemicircularGauge
        GaugeLabel                 matlab.ui.control.Label
        ButtonGroup                matlab.ui.container.ButtonGroup
        VerticaldivisionButton     matlab.ui.control.RadioButton
        HorizontaldivisionButton   matlab.ui.control.RadioButton
        AironlyButton              matlab.ui.control.RadioButton
        Slider                     matlab.ui.control.Slider
        SliderLabel                matlab.ui.control.Label
        ImpedancevsfrequencyLabel  matlab.ui.control.Label
        PressurevsGapLabel         matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       % --- Physical Constants ---
       L = 10e-6;              % Inductance (10 uH)
       epsilon0 = 8.854e-12;   % Permittivity of free space
       er_teflon = 2.1;        % Dielectric constant of Teflon
       A = 0.005 * 0.005;      % Plate Area (5mm x 5mm)
    
       % --- Mapping Limits (Raw Units) ---
       d_max = 5;              % Max Gap
       d_min = 1;              % Min Gap
       P_max = 350;            % Max Pressure (kPa)
       P_min = 10;             % Min Pressure (kPa)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   % Initialize Slider limits and starting value
    app.Slider.Limits = [app.d_min, app.d_max];
    app.Slider.Value = app.d_max;
    
    % Define the static Air-Baseline curve
    d_range = linspace(app.d_min, app.d_max, 100);
    P_range = app.P_min + (app.P_max - app.P_min) * ...
        ((app.d_max - d_range) / (app.d_max - app.d_min));

    % Clear and Plot the Baseline
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, P_range, d_range, 'b');
    plot(app.UIAxes, P_range, d_range, 'Color', [0.8 0.8 0.8], 'LineWidth', 0.5, 'HandleVisibility', 'off');
    
    % Add the 225 kPa Reference (Safety Line)
    xline(app.UIAxes, 225, '--k', 'Ref: 225 kPa', 'LineWidth', 1.5, 'LabelVerticalAlignment', 'bottom');
    
    grid(app.UIAxes, 'on');
    xlabel(app.UIAxes, 'Pressure (kPa)');
    ylabel(app.UIAxes, 'Gap (um)');
    hold(app.UIAxes, 'off');
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
  % 1. Get physical gap from slider
    d_curr = app.Slider.Value; 
    d_meters = d_curr * 1e-6;
    
    % --- FIX: Initialize C_total to prevent "Unrecognized" error ---
    C_total = (app.epsilon0 * app.A) / d_meters; 
    plotColor = [0 0.447 0.741]; % Default Blue (Air)

    k_cal = 1;   % calibration factor (effective permittivity of the selected layout)
    % 2. Calculate Capacitance based on Dielectric case selected
    % IMPORTANT: Ensure the text below matches your Button "Text" in App Designer
    switch app.ButtonGroup.SelectedObject.Text
        case 'Air only' % Air Only
            C_total = (app.epsilon0 * app.A) / d_meters;
            plotColor = [0 0.447 0.741]; 
            k_cal = 1;
        case 'Horizontal division' % Horizontal Division
            C_total = (app.epsilon0 * (app.A/2) / d_meters) + ...
                      (app.epsilon0 * app.er_teflon * (app.A/2) / d_meters);
            plotColor = [0.85 0.325 0.098]; 
            k_cal = (1 + app.er_teflon) / 2; 
        case 'Vertical division' % Vertical Division
            d_half = d_meters / 2;
            C1 = (app.epsilon0 * app.A) / d_half; 
            C2 = (app.epsilon0 * app.er_teflon * app.A) / d_half;
            C_total = (C1 * C2) / (C1 + C2);
            plotColor = [0.466 0.674 0.188];
            k_cal = 2 * app.er_teflon / (1 + app.er_teflon); 
    end 
    % 3. Electrical Output (Resonant Frequency)
    f_res = 1 / (2 * pi * sqrt(app.L * C_total));

  % 4. SENSOR LOGIC: remove the dielectric factor, then get the pressure
   C_cal = C_total / k_cal;
   d_apparent_m = (app.epsilon0 * app.A) / C_cal;
   d_apparent_um = d_apparent_m * 1e6;
   P_apparent = app.P_min + (app.P_max - app.P_min) * ...
             ((app.d_max - d_apparent_um) / (app.d_max - app.d_min));

    % 5. Update UI Components
    app.Gauge.Value = min(max(P_apparent, app.P_min), app.Gauge.Limits(2));
    app.GapLabel.Text = sprintf('Gap: %.8f um', d_curr);
    title(app.UIAxes, sprintf('Current Reading: %.1f kPa', P_apparent));
    title(app.UIAxes2, sprintf('Resonance: %.2f MHz', f_res/1e6));

   % 6. Update Graphs
    % Impedance Dip (UIAxes2) - Keep your existing code here
    f_sweep = linspace(f_res*0.8, f_res*1.2, 200);
    Z_mag = 1 - (0.9 ./ (1 + 50^2 * (f_sweep/f_res - f_res./f_sweep).^2));
    plot(app.UIAxes2, f_sweep/1e6, Z_mag, 'Color', plotColor, 'LineWidth', 2);
    grid(app.UIAxes2, 'on');

    % Update Current Point on UIAxes
    % We delete only the dot, NOT the whole axis, to keep the reference line
    delete(findobj(app.UIAxes, 'Tag', 'CurrentPoint'));
    hold(app.UIAxes, 'on');
    
    % Plot the point where the sensor currently "is"
    plot(app.UIAxes, P_apparent, d_curr, 'ro', ...
        'MarkerSize', 8, 'MarkerFaceColor', plotColor, 'Tag', 'CurrentPoint');
    
    hold(app.UIAxes, 'off');
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
        app.SliderValueChanged(); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Frequency (MHz)')
            ylabel(app.UIAxes2, 'Normalised |Z|')
            zlabel(app.UIAxes2, 'Z')
            app.PressurevsGapLabel = uilabel(app.UIFigure);
            app.PressurevsGapLabel.Text = ' Pressure vs Gap';
            app.ImpedancevsfrequencyLabel = uilabel(app.UIFigure);
            app.ImpedancevsfrequencyLabel.Text = 'Impedance vs frequency ';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.AironlyButton = uiradiobutton(app.ButtonGroup);
            app.AironlyButton.Text = 'Air only';
            app.AironlyButton.Value = true;
            app.HorizontaldivisionButton = uiradiobutton(app.ButtonGroup);
            app.HorizontaldivisionButton.Text = 'Horizontal division';
            app.VerticaldivisionButton = uiradiobutton(app.ButtonGroup);
            app.VerticaldivisionButton.Text = 'Vertical division';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [10 435];
            app.GapLabel = uilabel(app.UIFigure);
            app.GapLabel.Text = 'Gap';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tpms

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end